classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NhpphngtrnhviphnEditField       matlab.ui.control.EditField
        NhpphngtrnhviphnEditFieldLabel  matlab.ui.control.Label
        VButton                         matlab.ui.control.Button
        ChnphngphpListBox               matlab.ui.control.ListBox
        ChnphngphpListBoxLabel          matlab.ui.control.Label
        SaisEditField                   matlab.ui.control.NumericEditField
        SaisEditFieldLabel              matlab.ui.control.Label
        SonconNEditField                matlab.ui.control.NumericEditField
        SonconNEditFieldLabel           matlab.ui.control.Label
        GiihncuixNEditField             matlab.ui.control.NumericEditField
        GiihncuixNEditFieldLabel        matlab.ui.control.Label
        Giihnux0EditField               matlab.ui.control.NumericEditField
        Giihnux0EditFieldLabel          matlab.ui.control.Label
        iukinuy0EditField               matlab.ui.control.NumericEditField
        iukinuy0EditFieldLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            value = app.ChnphngphpListBox.Value;
if (value == "Ơ le")
x0 = app.Giihnux0EditField.Value
xn = app.GiihncuixNEditField.Value
f = app.NhpphngtrnhviphnEditField.Value
y0 = app.iukinuy0EditField.Value
N = app.SonconNEditField.Value
fxy = str2func(['@(x, y) ', f])
[x, y] = ole(fxy, x0, xn, y0, N)
plot(app.UIAxes , x,y,'m-.')
elseif (value == "Hiện ẩn hình thang")
x0 = app.Giihnux0EditField.Value
xn = app.GiihncuixNEditField.Value
f = app.NhpphngtrnhviphnEditField.Value
y0 = app.iukinuy0EditField.Value
N = app.SonconNEditField.Value
e = app.SaisEditField.Value
fxy = str2func(['@(x, y) ', f])
[x, y] = hienanhinhthang(fxy, x0, xn, y0, N,e)
plot(app.UIAxes , x,y,'r-*')
elseif (value == "Hiện ẩn trung điểm")x0 = app.Giihnux0EditField.Value
xn = app.GiihncuixNEditField.Value
f = app.NhpphngtrnhviphnEditField.Value
y0 = app.iukinuy0EditField.Value
N = app.SonconNEditField.Value
fxy = str2func(['@(x, y) ', f])
[x, y] = hienantrungdiem(fxy, x0, xn, y0, N)
plot(app.UIAxes , x,y,'g--')
else
x0 = app.Giihnux0EditField.Value
xn = app.GiihncuixNEditField.Value
f = app.NhpphngtrnhviphnEditField.Value
y0 = app.iukinuy0EditField.Value
N = app.SonconNEditField.Value
fxy = str2func(['@(x, y) ', f])
[x, y] = RK(fxy, x0, xn, y0, N)
plot(app.UIAxes , x,y)
end


        end

        % Value changed function: ChnphngphpListBox
        function ChnphngphpListBoxValueChanged(app, event)
            value = app.ChnphngphpListBox.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.iukinuy0EditFieldLabel = uilabel(app.UIFigure);
            app.iukinuy0EditFieldLabel.Text = 'Điều kiện đầu y0';
            app.iukinuy0EditField = uieditfield(app.UIFigure, 'numeric');
            app.iukinuy0EditField.Value = 1;
            app.Giihnux0EditFieldLabel = uilabel(app.UIFigure);
            app.Giihnux0EditFieldLabel.Text = 'Giới hạn đầu x0';
            app.Giihnux0EditField = uieditfield(app.UIFigure, 'numeric');
            app.GiihncuixNEditFieldLabel = uilabel(app.UIFigure);
            app.GiihncuixNEditFieldLabel.Text = 'Giới hạn cuối xN';
            app.GiihncuixNEditField = uieditfield(app.UIFigure, 'numeric');
            app.GiihncuixNEditField.Value = 5;
            app.SonconNEditFieldLabel = uilabel(app.UIFigure);
            app.SonconNEditFieldLabel.Text = 'Số đoạn con N';
            app.SonconNEditField = uieditfield(app.UIFigure, 'numeric');
            app.SonconNEditField.Value = 50;
            app.SaisEditFieldLabel = uilabel(app.UIFigure);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.SaisEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaisEditField.Value = 0.001;
            app.ChnphngphpListBoxLabel = uilabel(app.UIFigure);
            app.ChnphngphpListBoxLabel.Text = 'Chọn phương pháp';
            app.ChnphngphpListBox = uilistbox(app.UIFigure);
            app.ChnphngphpListBox.Items = {'Ơ le', 'Hiện ẩn hình thang', 'Hiện ẩn trung điểm', 'R-K'};
            app.ChnphngphpListBox.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpListBoxValueChanged, true);
            app.ChnphngphpListBox.Value = 'Ơ le';
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'Vẽ';
            app.NhpphngtrnhviphnEditFieldLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhviphnEditFieldLabel.Text = 'Nhập phương trình vi phân';
            app.NhpphngtrnhviphnEditField = uieditfield(app.UIFigure, 'text');
            app.NhpphngtrnhviphnEditField.Value = '(1-x)*y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end